classdef loadSessionDialogBox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        LoadSessionPanel             matlab.ui.container.Panel
        EnterFilenameEditFieldLabel  matlab.ui.control.Label
        EnterFilenameEditField       matlab.ui.control.EditField
        LoadButton                   matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
        TextArea                     matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        callingApp % Main app to return data to
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            movegui(app.UIFigure, 'center');
            app.callingApp = mainapp;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            fileName = app.EnterFilenameEditField.Value;
            L = length(fileName);
            
            if L <= 4
                fileName = [fileName, '.mat'];
            end
            
            try
                lastFour = fileName((L-3):L);
                if ~strcmp(lastFour, '.mat')
                    fileName = [fileName, '.mat'];
                end
            catch
                app.TextArea.Value = 'Error: Invalid filename';
            end
            
            try
                load(fileName, 'totalPotential', 'xCoordinates', 'dPsiTotal', ...
                    'dPsiCurrentMax', 'dPsiCountUntilUpdate', 'stepSize', ...
                    'xRange', 'yRange', 'waveFunctions', 'waveFunctionEnergies', 'trialsRan');
                
                enterLoadedData(app.callingApp, totalPotential, xCoordinates, dPsiTotal, ...
                    dPsiCurrentMax, dPsiCountUntilUpdate, stepSize, xRange, yRange, ...
                    waveFunctions, waveFunctionEnergies, trialsRan)
                
                delete(app);
                
            catch
                app.TextArea.Value = 'Error: Invalid filename';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LoadSessionPanel = uipanel(app.UIFigure);
            app.LoadSessionPanel.Title = 'Load Session';
            app.EnterFilenameEditFieldLabel = uilabel(app.LoadSessionPanel);
            app.EnterFilenameEditFieldLabel.Text = 'Enter Filename:';
            app.EnterFilenameEditField = uieditfield(app.LoadSessionPanel, 'text');
            app.EnterFilenameEditField.Tooltip = {''};
            app.LoadButton = uibutton(app.LoadSessionPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.CancelButton = uibutton(app.LoadSessionPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.TextArea = uitextarea(app.LoadSessionPanel);
            app.TextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = loadSessionDialogBox(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end