classdef modifyAdvancedSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ModifyAdvancedSettingsPanel  matlab.ui.container.Panel
        SaveButton                   matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
        MaximumdPsiperStepEditFieldLabel  matlab.ui.control.Label
        MaximumdPsiperStepEditField  matlab.ui.control.NumericEditField
        MinimumdPsiperStepEditFieldLabel  matlab.ui.control.Label
        MinimumdPsiperStepEditField  matlab.ui.control.NumericEditField
        StepsperdPsiUpdateEditFieldLabel  matlab.ui.control.Label
        StepsperdPsiUpdateEditField  matlab.ui.control.NumericEditField
        TextArea                     matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        callingApp % Main app to return data to
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, dPsiAbsoluteMax, dPsiAbsoluteMin, dPsiChangeRate)
            movegui(app.UIFigure, 'center');
            app.callingApp = mainapp;
            
            app.MaximumdPsiperStepEditField.Value = dPsiAbsoluteMax;
            app.MinimumdPsiperStepEditField.Value = dPsiAbsoluteMin;
            app.StepsperdPsiUpdateEditField.Value = dPsiChangeRate;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if app.MaximumdPsiperStepEditField.Value < app.MinimumdPsiperStepEditField.Value
                app.TextArea.Value = 'Error: Maximum dPsi must be greater than Minimum dPsi';
                disp('hello');
            else
                updateAdvancedSettings(app.callingApp, app.MaximumdPsiperStepEditField.Value, ...
                    app.MinimumdPsiperStepEditField.Value, app.StepsperdPsiUpdateEditField.Value);
                delete(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ModifyAdvancedSettingsPanel = uipanel(app.UIFigure);
            app.ModifyAdvancedSettingsPanel.Title = 'Modify Advanced Settings';
            app.SaveButton = uibutton(app.ModifyAdvancedSettingsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.ModifyAdvancedSettingsPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.MaximumdPsiperStepEditFieldLabel = uilabel(app.ModifyAdvancedSettingsPanel);
            app.MaximumdPsiperStepEditFieldLabel.Text = 'Maximum dPsi per Step';
            app.MaximumdPsiperStepEditField = uieditfield(app.ModifyAdvancedSettingsPanel, 'numeric');
            app.MaximumdPsiperStepEditField.Limits = [0 100];
            app.MinimumdPsiperStepEditFieldLabel = uilabel(app.ModifyAdvancedSettingsPanel);
            app.MinimumdPsiperStepEditFieldLabel.Text = 'Minimum dPsi per Step';
            app.MinimumdPsiperStepEditField = uieditfield(app.ModifyAdvancedSettingsPanel, 'numeric');
            app.MinimumdPsiperStepEditField.Limits = [0 100];
            app.StepsperdPsiUpdateEditFieldLabel = uilabel(app.ModifyAdvancedSettingsPanel);
            app.StepsperdPsiUpdateEditFieldLabel.Text = 'Steps per dPsi Update';
            app.StepsperdPsiUpdateEditField = uieditfield(app.ModifyAdvancedSettingsPanel, 'numeric');
            app.StepsperdPsiUpdateEditField.Limits = [0 Inf];
            app.StepsperdPsiUpdateEditField.RoundFractionalValues = 'on';
            app.TextArea = uitextarea(app.ModifyAdvancedSettingsPanel);
            app.TextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = modifyAdvancedSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end