classdef saveCurrentSessionDialogBox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SaveCurrentSessionPanel      matlab.ui.container.Panel
        EnterFilenameEditFieldLabel  matlab.ui.control.Label
        EnterFilenameEditField       matlab.ui.control.EditField
        SaveButton                   matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
        TextArea                     matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        
        callingApp
        
        totalPotentialStored
        xCoordinatesStored
        dPsiTotalStored
        dPsiCurrentMaxStored
        dPsiCountUntilUpdateStored
        stepSizeStored
        xRangeStored
        yRangeStored
        waveFunctionsStored
        waveFunctionEnergiesStored
        trialsRanStored
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, totalPotential, xCoordinates, dPsiTotal, dPsiCurrentMax, dPsiCountUntilUpdate, stepSize, xRange, yRange, waveFunctions, waveFunctionEnergies, trialsRan)
        
            movegui(app.UIFigure, 'center');
            app.callingApp = mainapp;
            
            app.totalPotentialStored = totalPotential;
            app.xCoordinatesStored = xCoordinates;
            app.dPsiTotalStored = dPsiTotal;
            app.dPsiCurrentMaxStored = dPsiCurrentMax;
            app.dPsiCountUntilUpdateStored = dPsiCountUntilUpdate;
            app.stepSizeStored = stepSize;
            app.xRangeStored = xRange;
            app.yRangeStored = yRange;
            app.waveFunctionsStored = waveFunctions;
            app.waveFunctionEnergiesStored = waveFunctionEnergies;
            app.trialsRanStored = trialsRan;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
        
            fileName = app.EnterFilenameEditField.Value;
            L = length(fileName);
            if L <= 4
                fileName = [fileName, '.mat'];
            end
            try
                L = length(fileName);
                lastFour = fileName((L-3):L);
                if ~strcmp(lastFour, '.mat')
                    fileName = [filename, '.mat'];
                end
                
            catch
                app.TextArea.Value = 'Error: Invalid filename';
            end
            
            totalPotential = app.totalPotentialStored;
            xCoordinates = app.xCoordinatesStored;
            dPsiTotal = app.dPsiTotalStored;
            dPsiCurrentMax = app.dPsiCurrentMaxStored;
            dPsiCountUntilUpdate = app.dPsiCountUntilUpdateStored;
            stepSize = app.stepSizeStored;
            xRange = app.xRangeStored;
            yRange = app.yRangeStored;
            waveFunctions = app.waveFunctionsStored;
            waveFunctionEnergies = app.waveFunctionEnergiesStored;
            trialsRan = app.trialsRanStored;
            
            try
                save(fileName, 'totalPotential', 'xCoordinates', 'dPsiTotal', ...
                    'dPsiCurrentMax', 'dPsiCountUntilUpdate', 'stepSize', ...
                    'xRange', 'yRange', 'waveFunctions', 'waveFunctionEnergies', 'trialsRan');
                delete(app);
            catch
                app.TextArea.Value = 'Error: Invalid filename';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SaveCurrentSessionPanel = uipanel(app.UIFigure);
            app.SaveCurrentSessionPanel.Title = 'Save Current Session';
            app.EnterFilenameEditFieldLabel = uilabel(app.SaveCurrentSessionPanel);
            app.EnterFilenameEditFieldLabel.Text = 'Enter Filename:';
            app.EnterFilenameEditField = uieditfield(app.SaveCurrentSessionPanel, 'text');
            app.EnterFilenameEditField.Tooltip = {''};
            app.SaveButton = uibutton(app.SaveCurrentSessionPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.SaveCurrentSessionPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.TextArea = uitextarea(app.SaveCurrentSessionPanel);
            app.TextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = saveCurrentSessionDialogBox(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end