classdef stochasticPotentialSolver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SaveSessionMenu                 matlab.ui.container.Menu
        LoadSessionMenu                 matlab.ui.container.Menu
        ManageSettingsMenu              matlab.ui.container.Menu
        EditAdvancedSettingsMenu        matlab.ui.container.Menu
        UpdateDefaultSettingsMenu       matlab.ui.container.Menu
        RestoreDefaultSettingsMenu      matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout2                     matlab.ui.container.GridLayout
        UIAxes                          matlab.ui.control.UIAxes
        GridLayout3                     matlab.ui.container.GridLayout
        SquareWellButton                matlab.ui.control.Button
        SlantedWellButton               matlab.ui.control.Button
        HarmonicOscillatorButton        matlab.ui.control.Button
        DoubleWellButton                matlab.ui.control.Button
        LennardJonesButton              matlab.ui.control.Button
        GridLayout6                     matlab.ui.container.GridLayout
        TextArea                        matlab.ui.control.TextArea
        UITable                         matlab.ui.control.Table
        GridLayout4                     matlab.ui.container.GridLayout
        PotentialAEditFieldLabel        matlab.ui.control.Label
        PotentialAEditField             matlab.ui.control.EditField
        xminAEditFieldLabel             matlab.ui.control.Label
        xminAEditField                  matlab.ui.control.NumericEditField
        xmaxAEditFieldLabel             matlab.ui.control.Label
        xmaxAEditField                  matlab.ui.control.NumericEditField
        PotentialBEditFieldLabel        matlab.ui.control.Label
        PotentialBEditField             matlab.ui.control.EditField
        xminBEditFieldLabel             matlab.ui.control.Label
        xminBEditField                  matlab.ui.control.NumericEditField
        xmaxBEditFieldLabel             matlab.ui.control.Label
        xmaxBEditField                  matlab.ui.control.NumericEditField
        ResetPotentialsButton           matlab.ui.control.Button
        yminGlobalEditFieldLabel        matlab.ui.control.Label
        yminGlobalEditField             matlab.ui.control.NumericEditField
        ymaxGlobalEditFieldLabel        matlab.ui.control.Label
        ymaxGlobalEditField             matlab.ui.control.NumericEditField
        ResetyaxisButton                matlab.ui.control.Button
        GridLayout5                     matlab.ui.container.GridLayout
        KeepUpdatingButton              matlab.ui.control.StateButton
        FindNextExcitedStateButton      matlab.ui.control.Button
        UpdateOnceButton                matlab.ui.control.Button
        ShowAllStatesFoundButton        matlab.ui.control.StateButton
        ShowRealProbabilityDensityButton  matlab.ui.control.StateButton
        StepByButtonGroup               matlab.ui.container.ButtonGroup
        Button_1                        matlab.ui.control.ToggleButton
        Button_10                       matlab.ui.control.ToggleButton
        Button_100                      matlab.ui.control.ToggleButton
        Button_1000                     matlab.ui.control.ToggleButton
        CustomEditField                 matlab.ui.control.NumericEditField
        CustomButton                    matlab.ui.control.ToggleButton
        SamplePointCountEditFieldLabel  matlab.ui.control.Label
        SamplePointCountEditField       matlab.ui.control.NumericEditField
    end

    properties (Access = private)
        
        dPsiAbsoluteMax = 0.25 % Absolute maximum of dPsiMax
        dPsiAbsoluteMin = 0.01; % Absolute minimum of dPsiMax
        dPsiChangeRate = 100; % Number of accepted steps before adjusting dPsiMax
        
        V % Total Potential
        X % Set of x points
        Psi % Guess for wave function
        dPsi % Average change of wave function
        dPsiMax % Iterative maximum change of wave function
        dPsiCount % Number of accepted changes
        orthogonalPsi % Previous wave functions
        orthogonalE % Previous wave function energies
        minE % Best energy for wave function
        dx % Step Size
        Ntrials % Number of trials attempted
        xRangeG % Global x range
        yRangeG % Global y range
    end
    
    methods (Access = private)
        
        function [goodInput, VA, VB, xRangeA, xRangeB] = validateInput(app)
        
            potentialA = app.PotentialAEditField.Value;
            xminA = app.xminAEditField.Value;
            xmaxA = app.xmaxAEditField.Value;
            
            potentialB = app.PotentialBEditField.Value;
            xminB = app.xminBEditField.Value;
            xmaxB = app.xmaxBEditField.Value;
            
            numPoints = app.SamplePointCountEditField.Value;
            
            yminGlobal = app.yminGlobalEditField.Value;
            ymaxGlobal = app.ymaxGlobalEditField.Value;
            
            currentText = app.TextArea.Value;
            
            app.dx = max([xmaxA - xminA, xmaxB - xminB]) / numPoints;
            xRangeA = [xminA, xmaxA];
            xRangeB = [xminB, xmaxB];
            app.xRangeG = [min(xminA, xminB), max(xmaxA, xmaxB)];
            app.yRangeG = [yminGlobal, ymaxGlobal];
            
            errorMessage = currentText;
            if strcmp(errorMessage{1}, 'Valid Input Recieved')
                errorMessage = {''};
                index = 1;
            else
                index = length(errorMessage) + 1;
            end
            
            goodInput = 1;
            
            try
                VA = str2func(['@(x) ', potentialA]);
                VA(xminA:app.dx:xmaxA);
                VA(xminB:app.dx:xmaxB);
            catch
                errorMessage{index} = 'Error: Potential A must be constant or a function of x and must conform with MATLAB function syntax';
                VA = 0;
                index = index + 1;
                goodInput = 0;
            end
           
            try
                VB = str2func(['@(x) ', potentialB]);
                VB(xminA:app.dx:xmaxA);
                VB(xminB:app.dx:xmaxB);
            catch
                errorMessage{index} = 'Error: Potential B must be constant or a function of x and must conform with MATLAB function syntax';
                VB = 0;
                index = index + 1;
                goodInput = 0;
            end
       
            if xminA >= xmaxA
                errorMessage{index} = 'Error: x-min A must be less than x-max A';
                index = index + 1;
                goodInput = 0;
            end
            
            if xminB >= xmaxB
                errorMessage{index} = 'Error: x-min B must be less than x-max B';
                index = index + 1;
                goodInput = 0;
            end
            
            if yminGlobal >= ymaxGlobal
                errorMessage{index} = 'Error: y-min Global must be less than y-max Global';
                goodInput = 0;
            end

            app.TextArea.Value = errorMessage;
        end
        
        function initializePlot(app, VA, VB, xRangeA, xRangeB)
        
            app.Ntrials = 0;
            app.dPsiMax = app.dPsiAbsoluteMax;
            app.dPsi = 0;
            app.dPsiCount = 0;
            
            app.orthogonalPsi = {};
            app.orthogonalE = {};
            app.X = app.xRangeG(1):app.dx:app.xRangeG(2);
            app.V = zeros(1, length(app.X));
            
            for i = 1:length(app.X)
                x = app.X(i);
                if (x >= xRangeA(1)) && (x <= xRangeA(2))
                    app.V(i) = app.V(i) + VA(x);
                end
                
                if (x >= xRangeB(1)) && (x <= xRangeB(2))
                    app.V(i) = app.V(i) + VB(x);
                end
            end
            
            app.Psi = rand(1, length(app.X)) - 0.25;
            app.Psi(1) = 0;
            app.Psi(end) = 0;
            normalizePsi(app);
            makeOrthogonal(app);
            app.minE = calcEnergy(app);
            
            updatePlot(app, 1)
        end
        
        function iterateWaveFunction(app, singleClick)

            while app.KeepUpdatingButton.Value || singleClick
                
                if app.Button_1.Value
                    steps = 1;
                elseif app.Button_10.Value
                    steps = 10;
                elseif app.Button_100.Value
                    steps = 100;
                elseif app.Button_1000.Value
                    steps = 1000;
                elseif app.CustomButton.Value
                    steps = app.CustomEditField.Value;
                end
                
                RNS = rand(steps, 3);
                for i = 1:steps

                    n = 2 + floor(RNS(i, 1) * (length(app.X) - 2));
                    m = n + floor(RNS(i, 2) * (length(app.X) - n));

                    psi_old = app.Psi;
                    app.Psi(n:m) = app.Psi(n:m) + (2 * (RNS(i, 3) - 0.5) * app.dPsiMax);
                    normalizePsi(app);
                    makeOrthogonal(app);
                    newE = calcEnergy(app);
                    if (newE < app.minE) 
                        app.minE = newE;
                        app.dPsi = app.dPsi + mean(abs(m - n) * abs(app.Psi - psi_old));
                        app.dPsiCount = app.dPsiCount + 1;
                        
                        if mod(app.dPsiCount, app.dPsiChangeRate) == 0
                            app.dPsiMax = min([app.dPsi / app.dPsiCount, app.dPsiAbsoluteMax]);
                            app.dPsiMax = max([app.dPsiAbsoluteMin, app.dPsiMax]);
                            app.dPsi = 0;
                            app.dPsiCount = 0;
                        end
                    else
                        app.Psi = psi_old;
                    end
                    app.Ntrials = app.Ntrials + 1;
          
                    if (~app.KeepUpdatingButton.Value) && (~singleClick)
                        break;
                    end
                end
                if (~app.KeepUpdatingButton.Value) && (~singleClick)
                    break;
                end
                updatePlot(app, 0);
                currentText = app.TextArea.Value;
                currentText{1} = sprintf('Current Energy: %f', app.minE);
                currentText{2} = sprintf('Number of Trials: %d', app.Ntrials);
                app.TextArea.Value = currentText;
                
                if singleClick
                    break;
                end
            end

        end
        
        function updatePlot(app, resetAxes)
            
            plot(app.UIAxes, app.X, app.V, 'k', 'LineWidth', 3);
            hold(app.UIAxes, 'on');
            
            if app.ShowRealProbabilityDensityButton.Value
                plot(app.UIAxes, app.X, app.Psi .* app.Psi, 'r', 'LineWidth', 3);
                
                if app.ShowAllStatesFoundButton.Value
                    for i = 1:length(app.orthogonalPsi)
                        plot(app.UIAxes, app.X, app.orthogonalPsi{i} .* app.orthogonalPsi{i}, 'LineWidth', 3);
                    end
                end
            else
                plot(app.UIAxes, app.X, app.Psi, 'r', 'LineWidth', 3);
                
                if app.ShowAllStatesFoundButton.Value
                    for i = 1:length(app.orthogonalPsi)
                        plot(app.UIAxes, app.X, app.orthogonalPsi{i}, 'LineWidth', 3);
                    end
                end
            end
            
            hold(app.UIAxes, 'off');
            if resetAxes
                xlim(app.UIAxes, app.xRangeG);
                ylim(app.UIAxes, app.yRangeG);
                title(app.UIAxes, '');
                xlabel(app.UIAxes, 'x', 'FontSize', 12, 'FontWeight', 'Bold');
                ylabel(app.UIAxes, 'V(x) / \psi(x)', 'FontSize', 12, 'FontWeight', 'Bold');
            end

            drawnow;
        end

        function normalizePsi(app)
            app.Psi = app.Psi / sqrt(trapz(app.Psi .^ 2) .* app.dx);
        end
        
        function makeOrthogonal(app)
            for i = 1:length(app.orthogonalPsi)
                projection = app.dx * trapz(app.Psi .* app.orthogonalPsi{i});
                app.Psi = app.Psi - (projection .* app.orthogonalPsi{i});
                normalizePsi(app);
            end
        end
        
        function E = calcEnergy(app)
            dx2 = app.dx ^ -2;
            norm = app.Psi .* app.Psi .* app.dx;
            E = 0;
        
            for i = 2:(length(app.X)-1)
                laplacePsi = (app.Psi(i+1) + app.Psi(i-1) - 2*app.Psi(i)) * dx2;
                E = E + (norm(i) .* app.V(i)) - (0.5 * app.dx * app.Psi(i) * laplacePsi); 
            end
            E = E / sum(norm);
        end
        
        function applyDefaultSettings(app)
        
            load('DefaultSettings.mat', 'VA', 'xminA', 'xmaxA', ...
                'VB', 'xminB', 'xmaxB', 'SPC', 'yminG', 'ymaxG', ...
                'stepBy', 'stepByCustom', 'showAllStates', 'showRealProbability', ...
                'dPsiAMax', 'dPsiAMin', 'dPsiCRate');
        
            app.PotentialAEditField.Value = VA;
            app.xminAEditField.Value = xminA;
            app.xmaxAEditField.Value = xmaxA;
            app.PotentialBEditField.Value = VB;
            app.xminBEditField.Value = xminB;
            app.xmaxBEditField.Value = xmaxB;
            app.SamplePointCountEditField.Value = SPC;
            app.yminGlobalEditField.Value = yminG;
            app.ymaxGlobalEditField.Value = ymaxG;
            if stepBy == 1
                app.Button_1.Value = 1;
            elseif stepBy == 2
                app.Button_10.Value = 1;
            elseif stepBy == 3
                app.Button_100.Value = 1;
            elseif stepBy == 4
                app.Button_1000.Value = 1;
            else
                app.CustomButton.Value = 1;
            end
            app.CustomEditField.Value = stepByCustom;
            app.ShowAllStatesFoundButton.Value = showAllStates;
            app.ShowRealProbabilityDensityButton.Value = showRealProbability;
            app.dPsiAbsoluteMax = dPsiAMax;
            app.dPsiAbsoluteMin = dPsiAMin;
            app.dPsiChangeRate = dPsiCRate;
        end
    end
    
    methods (Access = public)
        
        function enterLoadedData(app, totalPotential, xCoordinates, dPsiTotal, ...
                    dPsiCurrentMax, dPsiCountUntilUpdate, stepSize, xRange, yRange, ...
                    waveFunctions, waveFunctionEnergies, trialsRan)
                
            app.V = totalPotential;
            app.X = xCoordinates;
            app.dPsi = dPsiTotal;
            app.dPsiMax = dPsiCurrentMax;
            app.dPsiCount = dPsiCountUntilUpdate;
            app.dx = stepSize;
            app.xRangeG = xRange;
            app.yRangeG = yRange;
            app.Ntrials = trialsRan;
            
            app.orthogonalPsi = cell(1, length(waveFunctions)-1);
            app.orthogonalE = cell(1, length(waveFunctionEnergies)-1);
            for i = 1:(length(waveFunctions)-1)
                app.orthogonalPsi{i} = waveFunctions{i};
                app.orthogonalE{i} = waveFunctionEnergies{i};
            end
            app.Psi = waveFunctions{end};
            app.minE = waveFunctionEnergies{end};
            
            tableData = cell(length(app.orthogonalE)+1, 1);
            tableData{1} = 'Energies Found:';
            for i = 1:length(app.orthogonalE)
                tableData{i+1} = app.orthogonalE{i};
            end
            app.UITable.Data = tableData;

            updatePlot(app, 1);
        end
        
        function updateAdvancedSettings(app, dPsiAbsoluteMaxNew, dPsiAbsoluteMinNew, dPsiChangeRate)
            app.dPsiAbsoluteMax = dPsiAbsoluteMaxNew;
            app.dPsiAbsoluteMin = dPsiAbsoluteMinNew;
            app.dPsiChangeRate = dPsiChangeRate;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.RowName = {};
            app.UITable.ColumnName = {};
            app.UITable.Data = {'Energies Found:'};
            movegui(app.UIFigure,'center');
            try
                applyDefaultSettings(app);
                [~, VA, VB, xRangeA, xRangeB] = validateInput(app);
                app.TextArea.Value = 'Valid Input Recieved';
                initializePlot(app, VA, VB, xRangeA, xRangeB);
            catch
                app.TextArea.Value = 'Error: DefaultSettings.mat not detected';
            end
        end

        % Button pushed function: ResetPotentialsButton
        function ResetPotentialsButtonPushed(app, event)
            app.KeepUpdatingButton.Value = 0;
            app.UITable.Data = {'Energies Found:'};
            [goodInput, VA, VB, xRangeA, xRangeB] = validateInput(app);
            if goodInput
                app.TextArea.Value = 'Valid Input Recieved';
                initializePlot(app, VA, VB, xRangeA, xRangeB);
            end
        end

        % Button pushed function: ResetyaxisButton
        function ResetyaxisButtonPushed(app, event)
        
            ymin = app.yminGlobalEditField.Value;
            ymax = app.ymaxGlobalEditField.Value;
            currentText = app.TextArea.Value;
            
            if ymin < ymax
                app.yRangeG = [ymin, ymax];
                updatePlot(app, 1);
                
                if length(currentText) == 3
                    newText = cell(1, 2);
                    newText{1} = currentText{1};
                    newText{2} = currentText{2};
                    app.TextArea.Value = newText;
                end
            else
                app.KeepUpdatingButton.Value = 0;
                newText = cell(1, 3);
                newText{1} = currentText{1};
                newText{2} = currentText{2};
                newText{3} = 'Error: y-min Global must be less than y-max Global';
                app.TextArea.Value = newText;
            end
        end

        % Button pushed function: UpdateOnceButton
        function UpdateOnceButtonPushed(app, event)
            app.KeepUpdatingButton.Value = 0;
            iterateWaveFunction(app, 1);
        end

        % Value changed function: KeepUpdatingButton
        function KeepUpdatingButtonValueChanged(app, event)
            pressed = app.KeepUpdatingButton.Value;
            if pressed
                iterateWaveFunction(app, 0);
            end
        end

        % Button pushed function: FindNextExcitedStateButton
        function FindNextExcitedStateButtonPushed(app, event)
            
            app.KeepUpdatingButton.Value = 0;
            app.Ntrials = 0;

            app.orthogonalPsi{length(app.orthogonalPsi) + 1} = app.Psi;
            app.orthogonalE{length(app.orthogonalE) + 1} = app.minE;
            
            app.Psi = rand(1, length(app.X)) - 0.25;
            app.Psi(1) = 0;
            app.Psi(end) = 0;
            normalizePsi(app);
            makeOrthogonal(app);
            app.minE = calcEnergy(app);
    
            tableData = cell(length(app.orthogonalE)+1, 1);
            tableData{1} = 'Energies Found:';
            for i = 1:length(app.orthogonalE)
                tableData{i+1} = app.orthogonalE{i};
            end
            app.UITable.Data = tableData;
            
            updatePlot(app, 0);
        end

        % Value changed function: ShowAllStatesFoundButton
        function ShowAllStatesFoundButtonValueChanged(app, event)
            updatePlot(app, 0)
        end

        % Value changed function: ShowRealProbabilityDensityButton
        function ShowRealProbabilityDensityButtonValueChanged(app, event)
            updatePlot(app, 0);
        end

        % Button pushed function: SquareWellButton
        function SquareWellButtonPushed(app, event)
            app.KeepUpdatingButton.Value = 0;
            
            app.PotentialAEditField.Value = '100';
            app.xminAEditField.Value = -2;
            app.xmaxAEditField.Value = 2;
            
            app.PotentialBEditField.Value = '-100';
            app.xminBEditField.Value = -1;
            app.xmaxBEditField.Value = 1;
            
            app.SamplePointCountEditField.Value = 100;
            
            app.yminGlobalEditField.Value = -0.5;
            app.ymaxGlobalEditField.Value = 1.5;
            
            ResetPotentialsButtonPushed(app, 0);
            ResetyaxisButtonPushed(app, 0);
        end

        % Button pushed function: SlantedWellButton
        function SlantedWellButtonPushed(app, event)
            app.KeepUpdatingButton.Value = 0;
            
            app.PotentialAEditField.Value = '(2 .* x) - 100';
            app.xminAEditField.Value = -1;
            app.xmaxAEditField.Value = 1;
            
            app.PotentialBEditField.Value = '100';
            app.xminBEditField.Value = -2;
            app.xmaxBEditField.Value = 2;
            
            app.SamplePointCountEditField.Value = 100;
            
            app.yminGlobalEditField.Value = -3;
            app.ymaxGlobalEditField.Value = 3;
            
            ResetPotentialsButtonPushed(app, 0);
            ResetyaxisButtonPushed(app, 0);
        end

        % Button pushed function: DoubleWellButton
        function DoubleWellButtonPushed(app, event)
            app.KeepUpdatingButton.Value = 0;
            
            app.PotentialAEditField.Value = '((x .^ 2) - 4) .* ((x .^ 2) - 1)';
            app.xminAEditField.Value = -3;
            app.xmaxAEditField.Value = 3;
            
            app.PotentialBEditField.Value = '0';
            app.xminBEditField.Value = -1;
            app.xmaxBEditField.Value = 1;
            
            app.SamplePointCountEditField.Value = 100;
            
            app.yminGlobalEditField.Value = -3;
            app.ymaxGlobalEditField.Value = 5;
            
            ResetPotentialsButtonPushed(app, 0);
            ResetyaxisButtonPushed(app, 0);
        end

        % Button pushed function: HarmonicOscillatorButton
        function HarmonicOscillatorButtonPushed(app, event)
            app.KeepUpdatingButton.Value = 0;
            
            app.PotentialAEditField.Value = '(x .^ 2)';
            app.xminAEditField.Value = -3;
            app.xmaxAEditField.Value = 3;
            
            app.PotentialBEditField.Value = '0';
            app.xminBEditField.Value = -2;
            app.xmaxBEditField.Value = 2;
            
            app.SamplePointCountEditField.Value = 100;
            
            app.yminGlobalEditField.Value = -0.5;
            app.ymaxGlobalEditField.Value = 1.5;
            
            ResetPotentialsButtonPushed(app, 0);
            ResetyaxisButtonPushed(app, 0);
        end

        % Button pushed function: LennardJonesButton
        function LennardJonesButtonPushed(app, event)
            app.KeepUpdatingButton.Value = 0;
            
            app.PotentialAEditField.Value = '40 .* ((x .^ -12) - (x .^ -6))';
            app.xminAEditField.Value = 0.5;
            app.xmaxAEditField.Value = 5;
            
            app.PotentialBEditField.Value = '0';
            app.xminBEditField.Value = 0.5;
            app.xmaxBEditField.Value = 5;
            
            app.SamplePointCountEditField.Value = 100;
            
            app.yminGlobalEditField.Value = -2;
            app.ymaxGlobalEditField.Value = 2;
            
            ResetPotentialsButtonPushed(app, 0);
            ResetyaxisButtonPushed(app, 0);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            app.KeepUpdatingButton.Value = 0;

            selection = uiconfirm(app.UIFigure,'Warning: Current session data will be lost. Would you like to save before exiting?', ...
                'Exiting Session', 'Options',{'Save First','Exit without Saving','Cancel'},...
           'DefaultOption', 1, 'CancelOption', 3, 'Icon','warning');
       
            switch selection
                case 'Save First'
                    SaveSessionMenuSelected(app, 1);
                    delete(app)
                case 'Exit without Saving'
                    delete(app)
                case 'Cancel' 
            end
        end

        % Menu selected function: SaveSessionMenu
        function SaveSessionMenuSelected(app, event)
            
            app.KeepUpdatingButton.Value = 0;
            
            totalPotential = app.V;
            xCoordinates = app.X;
            dPsiTotal = app.dPsi;
            dPsiCurrentMax = app.dPsiMax;
            dPsiCountUntilUpdate = app.dPsiCount;
            stepSize = app.dx;
            xRange = app.xRangeG;
            yRange = app.yRangeG;
            trialsRan = app.Ntrials;
            
            waveFunctions = cell(1, length(app.orthogonalPsi)+1);
            waveFunctionEnergies = cell(1, length(app.orthogonalE)+1);
            for i = 1:length(app.orthogonalPsi)
                waveFunctions{i} = app.orthogonalPsi{i};
                waveFunctionEnergies{i} = app.orthogonalE{i};
            end
            waveFunctions{end} = app.Psi;
            waveFunctionEnergies{end} = app.minE;
            
            try
                saveCurrentSessionDialogBox(app, totalPotential, xCoordinates, dPsiTotal, ...
                        dPsiCurrentMax, dPsiCountUntilUpdate, stepSize, xRange, yRange, ...
                        waveFunctions, waveFunctionEnergies, trialsRan);
            catch
            end
        end

        % Menu selected function: LoadSessionMenu
        function LoadSessionMenuSelected(app, event)
            app.KeepUpdatingButton.Value = 0;  
            loadSessionDialogBox(app);
        end

        % Menu selected function: EditAdvancedSettingsMenu
        function EditAdvancedSettingsMenuSelected(app, event)
            app.KeepUpdatingButton.Value = 0;
            modifyAdvancedSettings(app, app.dPsiAbsoluteMax, app.dPsiAbsoluteMin, app.dPsiChangeRate);
        end

        % Menu selected function: RestoreDefaultSettingsMenu
        function RestoreDefaultSettingsMenuSelected(app, event)
            
            VA = '(x .^ 2)';
            xminA = -3;
            xmaxA = 3;
            VB = '0';
            xminB = -2;
            xmaxB = 2;
            SPC = 100;
            yminG = -0.5;
            ymaxG = 1.5;
            stepBy = 4;
            stepByCustom = 500;
            showAllStates = 1;
            showRealProbability = 0;
            dPsiAMax = 0.25;
            dPsiAMin = 0.01;
            dPsiCRate = 100;
            
            save('DefaultSettings.mat', 'VA', 'xminA', 'xmaxA', ...
                'VB', 'xminB', 'xmaxB', 'SPC', 'yminG', 'ymaxG', ...
                'stepBy', 'stepByCustom', 'showAllStates', 'showRealProbability', ...
                'dPsiAMax', 'dPsiAMin', 'dPsiCRate');
        end

        % Menu selected function: UpdateDefaultSettingsMenu
        function UpdateDefaultSettingsMenuSelected(app, event)
            
            VA = app.PotentialAEditField.Value;
            xminA = app.xminAEditField.Value;
            xmaxA = app.xmaxAEditField.Value;
            VB = app.PotentialBEditField.Value;
            xminB = app.xminBEditField.Value;
            xmaxB = app.xmaxBEditField.Value;
            SPC = app.SamplePointCountEditField.Value;
            yminG = app.yminGlobalEditField.Value;
            ymaxG = app.ymaxGlobalEditField.Value;
            if app.Button_1.Value
                stepBy = 1;
            elseif app.Button_10.Value
                stepBy = 2;
            elseif app.Button_100.Value
                stepBy = 3;
            elseif app.Button_1000.Value
                stepBy = 4;
            elseif app.CustomButton.Value
                stepBy = 5;
            end
            stepByCustom = app.CustomEditField.Value;
            showAllStates = app.ShowAllStatesFoundButton.Value;
            showRealProbability = app.ShowRealProbabilityDensityButton.Value;
            dPsiAMax = app.dPsiAbsoluteMax;
            dPsiAMin = app.dPsiAbsoluteMin;
            dPsiCRate = app.dPsiChangeRate;
            
            save('DefaultSettings.mat', 'VA', 'xminA', 'xmaxA', ...
                'VB', 'xminB', 'xmaxB', 'SPC', 'yminG', 'ymaxG', ...
                'stepBy', 'stepByCustom', 'showAllStates', 'showRealProbability', ...
                'dPsiAMax', 'dPsiAMin', 'dPsiCRate');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SaveSessionMenu = uimenu(app.UIFigure);
            app.SaveSessionMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSessionMenuSelected, true);
            app.SaveSessionMenu.Text = 'Save Session';
            app.LoadSessionMenu = uimenu(app.UIFigure);
            app.LoadSessionMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSessionMenuSelected, true);
            app.LoadSessionMenu.Text = 'Load Session';
            app.ManageSettingsMenu = uimenu(app.UIFigure);
            app.ManageSettingsMenu.Text = 'Manage Settings';
            app.EditAdvancedSettingsMenu = uimenu(app.ManageSettingsMenu);
            app.EditAdvancedSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @EditAdvancedSettingsMenuSelected, true);
            app.EditAdvancedSettingsMenu.Text = 'Edit Advanced Settings';
            app.UpdateDefaultSettingsMenu = uimenu(app.ManageSettingsMenu);
            app.UpdateDefaultSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @UpdateDefaultSettingsMenuSelected, true);
            app.UpdateDefaultSettingsMenu.Text = 'Update Default Settings';
            app.RestoreDefaultSettingsMenu = uimenu(app.ManageSettingsMenu);
            app.RestoreDefaultSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @RestoreDefaultSettingsMenuSelected, true);
            app.RestoreDefaultSettingsMenu.Text = 'Restore Default Settings';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.SquareWellButton = uibutton(app.GridLayout3, 'push');
            app.SquareWellButton.ButtonPushedFcn = createCallbackFcn(app, @SquareWellButtonPushed, true);
            app.SquareWellButton.Text = 'Square Well';
            app.SlantedWellButton = uibutton(app.GridLayout3, 'push');
            app.SlantedWellButton.ButtonPushedFcn = createCallbackFcn(app, @SlantedWellButtonPushed, true);
            app.SlantedWellButton.Text = 'Slanted Well';
            app.HarmonicOscillatorButton = uibutton(app.GridLayout3, 'push');
            app.HarmonicOscillatorButton.ButtonPushedFcn = createCallbackFcn(app, @HarmonicOscillatorButtonPushed, true);
            app.HarmonicOscillatorButton.Text = 'Harmonic Oscillator';
            app.DoubleWellButton = uibutton(app.GridLayout3, 'push');
            app.DoubleWellButton.ButtonPushedFcn = createCallbackFcn(app, @DoubleWellButtonPushed, true);
            app.DoubleWellButton.Text = 'Double Well';
            app.LennardJonesButton = uibutton(app.GridLayout3, 'push');
            app.LennardJonesButton.ButtonPushedFcn = createCallbackFcn(app, @LennardJonesButtonPushed, true);
            app.LennardJonesButton.Text = 'Lennard-Jones';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.TextArea = uitextarea(app.GridLayout6);
            app.UITable = uitable(app.GridLayout6);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.PotentialAEditFieldLabel = uilabel(app.GridLayout4);
            app.PotentialAEditFieldLabel.Text = 'Potential A';
            app.PotentialAEditField = uieditfield(app.GridLayout4, 'text');
            app.PotentialAEditField.Value = '(x .^ 2) ';
            app.xminAEditFieldLabel = uilabel(app.GridLayout4);
            app.xminAEditFieldLabel.Text = 'x-min A';
            app.xminAEditField = uieditfield(app.GridLayout4, 'numeric');
            app.xminAEditField.Value = -3;
            app.xmaxAEditFieldLabel = uilabel(app.GridLayout4);
            app.xmaxAEditFieldLabel.Text = 'x-max A';
            app.xmaxAEditField = uieditfield(app.GridLayout4, 'numeric');
            app.xmaxAEditField.Value = 3;
            app.PotentialBEditFieldLabel = uilabel(app.GridLayout4);
            app.PotentialBEditFieldLabel.Text = 'Potential B';
            app.PotentialBEditField = uieditfield(app.GridLayout4, 'text');
            app.PotentialBEditField.Value = '0';
            app.xminBEditFieldLabel = uilabel(app.GridLayout4);
            app.xminBEditFieldLabel.Text = 'x-min B';
            app.xminBEditField = uieditfield(app.GridLayout4, 'numeric');
            app.xminBEditField.Value = -2;
            app.xmaxBEditFieldLabel = uilabel(app.GridLayout4);
            app.xmaxBEditFieldLabel.Text = 'x-max B';
            app.xmaxBEditField = uieditfield(app.GridLayout4, 'numeric');
            app.xmaxBEditField.Value = 2;
            app.ResetPotentialsButton = uibutton(app.GridLayout4, 'push');
            app.ResetPotentialsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetPotentialsButtonPushed, true);
            app.ResetPotentialsButton.Text = 'Reset Potentials';
            app.yminGlobalEditFieldLabel = uilabel(app.GridLayout4);
            app.yminGlobalEditFieldLabel.Text = 'y-min Global';
            app.yminGlobalEditField = uieditfield(app.GridLayout4, 'numeric');
            app.yminGlobalEditField.Value = -0.5;
            app.ymaxGlobalEditFieldLabel = uilabel(app.GridLayout4);
            app.ymaxGlobalEditFieldLabel.Text = 'y-max Global';
            app.ymaxGlobalEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ymaxGlobalEditField.Value = 1.5;
            app.ResetyaxisButton = uibutton(app.GridLayout4, 'push');
            app.ResetyaxisButton.ButtonPushedFcn = createCallbackFcn(app, @ResetyaxisButtonPushed, true);
            app.ResetyaxisButton.Text = 'Reset y-axis';
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.KeepUpdatingButton = uibutton(app.GridLayout5, 'state');
            app.KeepUpdatingButton.ValueChangedFcn = createCallbackFcn(app, @KeepUpdatingButtonValueChanged, true);
            app.KeepUpdatingButton.Text = 'Keep Updating';
            app.FindNextExcitedStateButton = uibutton(app.GridLayout5, 'push');
            app.FindNextExcitedStateButton.ButtonPushedFcn = createCallbackFcn(app, @FindNextExcitedStateButtonPushed, true);
            app.FindNextExcitedStateButton.Text = 'Find Next Excited State';
            app.UpdateOnceButton = uibutton(app.GridLayout5, 'push');
            app.UpdateOnceButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateOnceButtonPushed, true);
            app.UpdateOnceButton.Text = 'Update Once';
            app.ShowAllStatesFoundButton = uibutton(app.GridLayout5, 'state');
            app.ShowAllStatesFoundButton.ValueChangedFcn = createCallbackFcn(app, @ShowAllStatesFoundButtonValueChanged, true);
            app.ShowAllStatesFoundButton.Text = 'Show All States Found';
            app.ShowRealProbabilityDensityButton = uibutton(app.GridLayout5, 'state');
            app.ShowRealProbabilityDensityButton.ValueChangedFcn = createCallbackFcn(app, @ShowRealProbabilityDensityButtonValueChanged, true);
            app.ShowRealProbabilityDensityButton.Text = 'Show Real Probability Density';
            app.StepByButtonGroup = uibuttongroup(app.GridLayout4);
            app.StepByButtonGroup.Title = 'Step By:';
            app.Button_1 = uitogglebutton(app.StepByButtonGroup);
            app.Button_1.Text = '1';
            app.Button_10 = uitogglebutton(app.StepByButtonGroup);
            app.Button_10.Text = '10';
            app.Button_100 = uitogglebutton(app.StepByButtonGroup);
            app.Button_100.Text = '100';
            app.Button_1000 = uitogglebutton(app.StepByButtonGroup);
            app.Button_1000.Text = '1000';
            app.Button_1000.Value = true;
            app.CustomEditField = uieditfield(app.StepByButtonGroup, 'numeric');
            app.CustomEditField.Limits = [1 100000];
            app.CustomEditField.RoundFractionalValues = 'on';
            app.CustomEditField.Value = 500;
            app.CustomButton = uitogglebutton(app.StepByButtonGroup);
            app.CustomButton.Text = 'Custom';
            app.SamplePointCountEditFieldLabel = uilabel(app.GridLayout4);
            app.SamplePointCountEditFieldLabel.Text = 'Sample Point Count';
            app.SamplePointCountEditField = uieditfield(app.GridLayout4, 'numeric');
            app.SamplePointCountEditField.Limits = [10 1000];
            app.SamplePointCountEditField.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stochasticPotentialSolver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end